classdef axB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MatriceAEditFieldLabel  matlab.ui.control.Label
        MatriceAEditField       matlab.ui.control.EditField
        MatriceBEditFieldLabel  matlab.ui.control.Label
        MatriceBEditField       matlab.ui.control.EditField
        ResoudreButton          matlab.ui.control.Button
        DrawingButton           matlab.ui.control.Button
        solutionTextAreaLabel   matlab.ui.control.Label
        solutionTextArea        matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
        MotifDropDownLabel      matlab.ui.control.Label
        MotifDropDown           matlab.ui.control.DropDown
        RetournerButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResoudreButton
        function ResoudreButtonPushed(app, event)
            a=app.MatriceAEditField.Value;
            a=str2num(a);
            [ma,nacol]=size(a);
            
            b=app.MatriceBEditField.Value;
            b=str2num(b);
            [mb,nbcol]=size(b);
             c=inv(a)*b;
            c=num2str(c);
            %c=mat2str(c);


           app.solutionTextArea.Value=cellstr(c);
        end

        % Button pushed function: DrawingButton
        function DrawingButtonPushed(app, event)
            a=app.MatriceAEditField.Value;
            a=str2num(a);
            [ma,nacol]=size(a);
            
            b=app.MatriceBEditField.Value;
            b=str2num(b);
            [mb,nbcol]=size(b);
             x=inv(a)*b;
            y=a*x-b;
         plot(app.UIAxes,x,y,'-r');
    hold(app.UIAxes);
        end

        % Value changed function: MotifDropDown
        function MotifDropDownValueChanged(app, event)
            value = app.MotifDropDown.Value;
            value = app.MotifDropDown.Value;
            a=app.MatriceAEditField.Value;
            a=str2num(a);
            [ma,nacol]=size(a);
            
            b=app.MatriceBEditField.Value;
            b=str2num(b);
            [mb,nbcol]=size(b);
             x=inv(a)*b;
            y=a*x-b;
         
            switch(value)
                case 'red'
                    plot(app.UIAxes,x,y,'-r');
    hold(app.UIAxes);
                case 'green' 
                    plot(app.UIAxes,x,y,'-g');
    hold(app.UIAxes);
   case 'C' 
   case 'D'
       plot(app.UIAxes,x,y);
    hold(app.UIAxes);
   case 'F' 
                otherwise
                    plot(app.UIAxes,x,y);
    hold(app.UIAxes);
   end
        end

        % Button pushed function: RetournerButton
        function RetournerButtonPushed(app, event)
            homeInterface;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatriceAEditFieldLabel = uilabel(app.UIFigure);
            app.MatriceAEditFieldLabel.Text = 'MatriceA';
            app.MatriceAEditField = uieditfield(app.UIFigure, 'text');
            app.MatriceBEditFieldLabel = uilabel(app.UIFigure);
            app.MatriceBEditFieldLabel.Text = 'MatriceB';
            app.MatriceBEditField = uieditfield(app.UIFigure, 'text');
            app.ResoudreButton = uibutton(app.UIFigure, 'push');
            app.ResoudreButton.ButtonPushedFcn = createCallbackFcn(app, @ResoudreButtonPushed, true);
            app.ResoudreButton.Text = 'Resoudre';
            app.DrawingButton = uibutton(app.UIFigure, 'push');
            app.DrawingButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingButtonPushed, true);
            app.DrawingButton.Text = 'Drawing';
            app.solutionTextAreaLabel = uilabel(app.UIFigure);
            app.solutionTextAreaLabel.Text = 'solution';
            app.solutionTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MotifDropDownLabel = uilabel(app.UIFigure);
            app.MotifDropDownLabel.Text = 'Motif';
            app.MotifDropDown = uidropdown(app.UIFigure);
            app.MotifDropDown.Items = {'red', 'green', 'black', 'default'};
            app.MotifDropDown.ValueChangedFcn = createCallbackFcn(app, @MotifDropDownValueChanged, true);
            app.MotifDropDown.Value = 'red';
            app.RetournerButton = uibutton(app.UIFigure, 'push');
            app.RetournerButton.ButtonPushedFcn = createCallbackFcn(app, @RetournerButtonPushed, true);
            app.RetournerButton.Text = 'Retourner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end