classdef bisection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        aEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        resoudreButton           matlab.ui.control.Button
        presEditFieldLabel       matlab.ui.control.Label
        presEditField            matlab.ui.control.NumericEditField
        SolutionTextAreaLabel    matlab.ui.control.Label
        SolutionTextArea         matlab.ui.control.TextArea
        iterationjTextAreaLabel  matlab.ui.control.Label
        iterationjTextArea       matlab.ui.control.TextArea
        fctEditFieldLabel        matlab.ui.control.Label
        fctEditField             matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        DrawingButton            matlab.ui.control.Button
        RetournerButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resoudreButton
        function resoudreButtonPushed(app, event)
           a=app.aEditField.Value;
           b=app.bEditField.Value;
           fct=app.fctEditField.Value;
           pres=app.presEditField.Value;
           fct=strcat('@(x)',fct);
           fct=str2func(fct);


 c=0;        
ite=0; % iteration
while abs(b-a) > pres
c=(a+b)/2;
if (fct(a)*fct(c) <0 )%f(a)*f(c)<0
b=c;
else
a=c;
end
ite=ite+1;
end
s=mat2str(c);
iter=mat2str(ite);


   app.iterationjTextArea.Value=iter;
     app.SolutionTextArea.Value=s;


        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value= app.aEditField.Value;
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value= app.bEditField.Value;
            
        end

        % Value changed function: presEditField
        function presEditFieldValueChanged(app, event)
            value = app.presEditField.Value;
            
        end

        % Callback function
        function sTextAreaValueChanged(app, event)
           app.SolutionTextArea.Value=s;
            
        end

        % Callback function
        function iterTextAreaValueChanged(app, event)
            app.iterationjTextArea.Value=iter ;
            
        end

        % Callback function
        function sEditFieldValueChanged(app, event)
        value=app.sEditField.Value;
            
        end

        % Callback function
        function iterEditFieldValueChanged(app, event)
            value=  app.iterEditField.Value;
            
        end

        % Value changed function: fctEditField
        function fctEditFieldValueChanged(app, event)
            value = app.fctEditField.Value;
            
        end

        % Button pushed function: DrawingButton
        function DrawingButtonPushed(app, event)
             a=app.aEditField.Value;
           b=app.bEditField.Value;
     
           
          fct=app.fctEditField.Value;
           fct=strcat('@(x)',fct);
           fct=str2func(fct);
         
          fplot(app.UIAxes,fct,[a,b],'-r');
    hold(app.UIAxes);
        end

        % Button pushed function: RetournerButton
        function RetournerButtonPushed(app, event)
            homeInterface;
            delete(app);
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a ';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.resoudreButton = uibutton(app.UIFigure, 'push');
            app.resoudreButton.ButtonPushedFcn = createCallbackFcn(app, @resoudreButtonPushed, true);
            app.resoudreButton.Text = 'resoudre';
            app.presEditFieldLabel = uilabel(app.UIFigure);
            app.presEditFieldLabel.Text = 'pres';
            app.presEditField = uieditfield(app.UIFigure, 'numeric');
            app.presEditField.ValueChangedFcn = createCallbackFcn(app, @presEditFieldValueChanged, true);
            app.SolutionTextAreaLabel = uilabel(app.UIFigure);
            app.SolutionTextAreaLabel.Text = 'Solution';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.iterationjTextAreaLabel = uilabel(app.UIFigure);
            app.iterationjTextAreaLabel.Text = 'iterationj';
            app.iterationjTextArea = uitextarea(app.UIFigure);
            app.fctEditFieldLabel = uilabel(app.UIFigure);
            app.fctEditFieldLabel.Text = 'fct';
            app.fctEditField = uieditfield(app.UIFigure, 'text');
            app.fctEditField.ValueChangedFcn = createCallbackFcn(app, @fctEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DrawingButton = uibutton(app.UIFigure, 'push');
            app.DrawingButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingButtonPushed, true);
            app.DrawingButton.Text = 'Drawing';
            app.RetournerButton = uibutton(app.UIFigure, 'push');
            app.RetournerButton.ButtonPushedFcn = createCallbackFcn(app, @RetournerButtonPushed, true);
            app.RetournerButton.Text = 'Retourner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bisection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end