classdef newtonRaph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        x1EditFieldLabel   matlab.ui.control.Label
        x1EditField        matlab.ui.control.NumericEditField
        preEditFieldLabel  matlab.ui.control.Label
        preEditField       matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        nEditField         matlab.ui.control.NumericEditField
        fctEditFieldLabel  matlab.ui.control.Label
        fctEditField       matlab.ui.control.EditField
        resoudreButton     matlab.ui.control.Button
        sTextAreaLabel     matlab.ui.control.Label
        sTextArea          matlab.ui.control.TextArea
        DrawingButton      matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        aEditFieldLabel    matlab.ui.control.Label
        aEditField         matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        bEditField         matlab.ui.control.NumericEditField
        retournerButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resoudreButton
        function resoudreButtonPushed(app, event)
x1=app.x1EditField.Value;
pre=app.preEditField.Value;
n=app.nEditField.Value;
fct=app.fctEditField.Value;

y(1)=x1; 
precision=pre; 
fct=strcat('@(x)',fct);
fct=str2func(fct);
syms x;
dirv= diff(fct, x);

for i=2:n 
f=fct(y(i-1));
f=double(f);
div=subs (dirv,x,y(i-1));
div=double(div);

y(i)=y(i-1)-f/div;
if abs(y(i)-y(i-1))<=precision
x_precision=y(i); 
break;
end
end
x_precision=double(x_precision);

x_precision=num2str(x_precision);
app.sTextArea.Value=x_precision;


        end

        % Button pushed function: DrawingButton
        function DrawingButtonPushed(app, event)
            
            a=app.aEditField.Value;
           b=app.bEditField.Value;
     
           
          fct=app.fctEditField.Value;
           fct=strcat('@(x)',fct);
           fct=str2func(fct);
         
          fplot(app.UIAxes,fct,[a,b],'-r');
    hold(app.UIAxes);
        end

        % Button pushed function: retournerButton
        function retournerButtonPushed(app, event)
            homeInterface;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.preEditFieldLabel = uilabel(app.UIFigure);
            app.preEditFieldLabel.Text = 'pre';
            app.preEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.fctEditFieldLabel = uilabel(app.UIFigure);
            app.fctEditFieldLabel.Text = 'fct';
            app.fctEditField = uieditfield(app.UIFigure, 'text');
            app.resoudreButton = uibutton(app.UIFigure, 'push');
            app.resoudreButton.ButtonPushedFcn = createCallbackFcn(app, @resoudreButtonPushed, true);
            app.resoudreButton.Text = 'resoudre';
            app.sTextAreaLabel = uilabel(app.UIFigure);
            app.sTextAreaLabel.Text = 's';
            app.sTextArea = uitextarea(app.UIFigure);
            app.DrawingButton = uibutton(app.UIFigure, 'push');
            app.DrawingButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingButtonPushed, true);
            app.DrawingButton.Text = 'Drawing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.retournerButton = uibutton(app.UIFigure, 'push');
            app.retournerButton.ButtonPushedFcn = createCallbackFcn(app, @retournerButtonPushed, true);
            app.retournerButton.Text = 'retourner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtonRaph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end