classdef pointFixe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        resoudreButton         matlab.ui.control.Button
        x0EditFieldLabel       matlab.ui.control.Label
        x0EditField            matlab.ui.control.NumericEditField
        fctEditFieldLabel      matlab.ui.control.Label
        fctEditField           matlab.ui.control.EditField
        SolutionTextAreaLabel  matlab.ui.control.Label
        SolutionTextArea       matlab.ui.control.TextArea
        iterTextAreaLabel      matlab.ui.control.Label
        iterTextArea           matlab.ui.control.TextArea
        preEditFieldLabel      matlab.ui.control.Label
        preEditField           matlab.ui.control.NumericEditField
        DrawingButton          matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        xfEditFieldLabel       matlab.ui.control.Label
        xfEditField            matlab.ui.control.NumericEditField
        xiEditFieldLabel       matlab.ui.control.Label
        xiEditField            matlab.ui.control.NumericEditField
        RetournerButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resoudreButton
        function resoudreButtonPushed(app, event)
            
            x0=app.x0EditField.Value;
           pre=app.preEditField.Value;
           fct=app.fctEditField.Value;
           fct=strcat('@(x)',fct);
           fct=str2func(fct);
           

x1=fct(x0);
iter= 0 ; %la 1ère itération
while abs(x0-x1)>pre
x0=x1;
x1=fct(x0);
iter=iter+1;
end
x1=mat2str(x1);
iter=mat2str(iter);


   app.iterTextArea.Value=iter;
     app.SolutionTextArea.Value=x1;


        end

        % Button pushed function: DrawingButton
        function DrawingButtonPushed(app, event)
                x0=app.x0EditField.Value;
                xi=app.xiEditField.Value;
                xf=app.xfEditField.Value;
          
           fct=app.fctEditField.Value;
           fct=strcat('@(x)',fct);
           fct=str2func(fct);
           

          fplot(app.UIAxes,fct,[xi,xf],'-r');
    hold(app.UIAxes);
        end

        % Button pushed function: RetournerButton
        function RetournerButtonPushed(app, event)
            homeInterface;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.resoudreButton = uibutton(app.UIFigure, 'push');
            app.resoudreButton.ButtonPushedFcn = createCallbackFcn(app, @resoudreButtonPushed, true);
            app.resoudreButton.Text = 'resoudre';
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.fctEditFieldLabel = uilabel(app.UIFigure);
            app.fctEditFieldLabel.Text = 'fct';
            app.fctEditField = uieditfield(app.UIFigure, 'text');
            app.SolutionTextAreaLabel = uilabel(app.UIFigure);
            app.SolutionTextAreaLabel.Text = 'Solution';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.iterTextAreaLabel = uilabel(app.UIFigure);
            app.iterTextAreaLabel.Text = 'iter';
            app.iterTextArea = uitextarea(app.UIFigure);
            app.preEditFieldLabel = uilabel(app.UIFigure);
            app.preEditFieldLabel.Text = 'pre';
            app.preEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrawingButton = uibutton(app.UIFigure, 'push');
            app.DrawingButton.ButtonPushedFcn = createCallbackFcn(app, @DrawingButtonPushed, true);
            app.DrawingButton.Text = 'Drawing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.xfEditFieldLabel = uilabel(app.UIFigure);
            app.xfEditFieldLabel.Text = 'xf';
            app.xfEditField = uieditfield(app.UIFigure, 'numeric');
            app.xiEditFieldLabel = uilabel(app.UIFigure);
            app.xiEditFieldLabel.Text = 'xi';
            app.xiEditField = uieditfield(app.UIFigure, 'numeric');
            app.RetournerButton = uibutton(app.UIFigure, 'push');
            app.RetournerButton.ButtonPushedFcn = createCallbackFcn(app, @RetournerButtonPushed, true);
            app.RetournerButton.Text = 'Retourner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pointFixe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end